classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        WelcometotheStressFieldPanel    matlab.ui.container.Panel
        MinEpsilonEditFieldLabel        matlab.ui.control.Label
        MinEpsilonEditField             matlab.ui.control.NumericEditField
        LevelsEditFieldLabel            matlab.ui.control.Label
        LevelsEditField                 matlab.ui.control.NumericEditField
        SeedingStrategyDropDownLabel    matlab.ui.control.Label
        SeedingStrategyDropDown         matlab.ui.control.DropDown
        GenerateSeedPointsButton        matlab.ui.control.Button
        FunctionalityDropDownLabel      matlab.ui.control.Label
        FunctionalityDropDown           matlab.ui.control.DropDown
        SubmitButton                    matlab.ui.control.Button
        ExpertSettingsPanel             matlab.ui.container.Panel
        SnappingOptDropDownLabel        matlab.ui.control.Label
        SnappingOptDropDown             matlab.ui.control.DropDown
        PSLSmoothingCtrlLabel           matlab.ui.control.Label
        PSLSmoothingCtrlEditField       matlab.ui.control.NumericEditField
        MinPSLLengthCtrlEditFieldLabel  matlab.ui.control.Label
        MinPSLLengthCtrlEditField       matlab.ui.control.NumericEditField
        GPUAccDropDownLabel             matlab.ui.control.Label
        GPUAccDropDown                  matlab.ui.control.DropDown
        VolumeSeedPointsCtrlEditFieldLabel  matlab.ui.control.Label
        VolumeSeedPointsCtrlEditField   matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel          matlab.ui.control.Label
        StepSizeEditField               matlab.ui.control.NumericEditField
        InvestigationTab                matlab.ui.container.Tab
        PSLsResolutionCtrlPanel         matlab.ui.container.Panel
        IMMajorLabel                    matlab.ui.control.Label
        IMMajorDropDown                 matlab.ui.control.DropDown
        MajorSliderLabel                matlab.ui.control.Label
        MajorSlider                     matlab.ui.control.Slider
        IMMinorDropDownLabel            matlab.ui.control.Label
        IMMinorDropDown                 matlab.ui.control.DropDown
        MinorSliderLabel                matlab.ui.control.Label
        MinorSlider                     matlab.ui.control.Slider
        PreferencePanel                 matlab.ui.container.Panel
        PSLGeometryDropDownLabel        matlab.ui.control.Label
        PSLGeometryDropDown             matlab.ui.control.DropDown
        ColorSchemeDropDownLabel        matlab.ui.control.Label
        ColorSchemeDropDown             matlab.ui.control.DropDown
        LightsDropDownLabel             matlab.ui.control.Label
        LightsDropDown                  matlab.ui.control.DropDown
        ReflectionPropertyDropDownLabel  matlab.ui.control.Label
        ReflectionPropertyDropDown      matlab.ui.control.DropDown
        ScalarFieldEncodingDropDownLabel  matlab.ui.control.Label
        ScalarFieldEncodingDropDown     matlab.ui.control.DropDown
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        FilesMenu                       matlab.ui.container.Menu
        ImportDatavtkMenu               matlab.ui.container.Menu
        ResetMenu                       matlab.ui.container.Menu
        ClearAllMenu                    matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: ImportDatavtkMenu
        function ImportDatavtkMenuSelected(app, event)
            addpath('./src');
            GlobalVariables;
            axHandle_ = app.UIAxes;
            [fileName, dataPath] = uigetfile('.vtk', 'Select a Stress Field File to Open');
            if isnumeric(fileName) || isnumeric(dataPath), return; end
            ImportStressFields(strcat(dataPath,fileName));
            handleSilhoutte_ = DrawSilhouette();
            
            %%Initialize Key Parameters
            tracingStepWidth_ = eleSize_/1;
            minimumEpsilon_ = app.MinEpsilonEditField.Value;
            global seedSpan4VolumeOptCartesianMesh_; seedSpan4VolumeOptCartesianMesh_ = ceil(minimumEpsilon_/1.67);
        end

        % Menu selected function: ClearAllMenu
        function ClearAllMenuSelected(app, event)
            GlobalVariables;
            colorbar(app.UIAxes, 'off');
            rmpath('./src');
            cla(app.UIAxes);
            clc;
        end

        % Button pushed function: GenerateSeedPointsButton
        function GenerateSeedPointsButtonPushed(app, event)
            seedStrategy = app.SeedingStrategyDropDown.Value;
            GenerateSeedPoints(seedStrategy);
            global handleSeedPoints_;
            if ~isempty(handleSeedPoints_), set(handleSeedPoints_, 'visible', 'off'); handleSeedPoints_ = []; end
            handleSeedPoints_ = DrawSeedPoints();
        end

        % Value changed function: SnappingOptDropDown
        function SnappingOptDropDownValueChanged(app, event)
            value = app.SnappingOptDropDown.Value;
            global snappingOpt_; snappingOpt_ = value;
        end

        % Value changed function: GPUAccDropDown
        function GPUAccDropDownValueChanged(app, event)
            value = app.GPUAccDropDown.Value;
            global GPU_; GPU_ = value;
        end

        % Value changed function: PSLSmoothingCtrlEditField
        function PSLSmoothingCtrlEditFieldValueChanged(app, event)
            value = app.PSLSmoothingCtrlEditField.Value;
            global interceptionThreshold_; interceptionThreshold_ = value;
        end

        % Value changed function: MinPSLLengthCtrlEditField
        function MinPSLLengthCtrlEditFieldValueChanged(app, event)
            value = app.MinPSLLengthCtrlEditField.Value;
            global minLengthVisiblePSLs_; minLengthVisiblePSLs_ = value;
        end

        % Value changed function: VolumeSeedPointsCtrlEditField
        function VolumeSeedPointsCtrlEditFieldValueChanged(app, event)
            value = app.VolumeSeedPointsCtrlEditField.Value;
            global seedSpan4VolumeOptCartesianMesh_; seedSpan4VolumeOptCartesianMesh_ = value;
        end

        % Value changed function: MinEpsilonEditField
        function MinEpsilonEditFieldValueChanged(app, event)
            value = app.MinEpsilonEditField.Value;
            global minimumEpsilon_; minimumEpsilon_ = value;
            global seedSpan4VolumeOptCartesianMesh_; seedSpan4VolumeOptCartesianMesh_ = ceil(minimumEpsilon_/1.67);
        end

        % Menu selected function: ResetMenu
        function ResetMenuSelected(app, event)
            %%GUI
            app.SeedingStrategyDropDown.Value = 'Volume';            
            app.FunctionalityDropDown.Value = 'Major&Minor';
            app.SnappingOptDropDown.Value = 'OFF';            
            app.GPUAccDropDown.Value = 'OFF';            
            app.IMMajorDropDown.Value = 'Pure PSLs Density Ctrl';
            app.IMMinorDropDown.Value = 'Pure PSLs Density Ctrl';
            app.MajorSlider.Limits = [0 1]; app.MajorSlider.Value = 0;
            app.MinorSlider.Limits = [0 1]; app.MinorSlider.Value = 0;
            app.PSLGeometryDropDown.Value = 'Tube (T)';
            app.ScalarFieldEncodingDropDown.Value = 'None';
            app.ColorSchemeDropDown.Value = 'Red&Blue';
            app.LightsDropDown.Value = 'None';
            app.ReflectionPropertyDropDown.Value = 'None';
            
            %%variables
            global GPU_; GPU_ = app.GPUAccDropDown.Value;
            global snappingOpt_; snappingOpt_ = app.SnappingOptDropDown.Value;
            global majorPSLpool_; majorPSLpool_ = [];
            global minorPSLpool_; minorPSLpool_ = [];
            global majorCoordList_; majorCoordList_ = [];
            global minorCoordList_; minorCoordList_ = [];
            global handleSeedPoints_; handleSeedPoints_ = [];
            
            %%Clear Axes
            colorbar(app.UIAxes, 'off');
            cla(app.UIAxes);
            clc;
            
            %%Re-draw Silhoutte
            global handleSilhoutte_; handleSilhoutte_ = DrawSilhouette();
        end

        % Value changed function: StepSizeEditField
        function StepSizeEditFieldValueChanged(app, event)
            value = app.StepSizeEditField.Value;
            global tracingStepWidth_; tracingStepWidth_ = tracingStepWidth_*value;
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            global numLevels_; numLevels_ = app.LevelsEditField.Value;
            global minimumEpsilon_;
            global majorPSLindexList_; global minorPSLindexList_;
            global majorPSLpool_; global minorPSLpool_;
            global majorCoordList_; global minorCoordList_;
            global seedPointsHistory_; global seedPoints_; global seedPointsValence_;
            global tracingStepWidth_; global mergeTrigger_; global handleSeedPoints_;
            global functionality_; functionality_ = app.FunctionalityDropDown.Value;
            
            if ~isempty(majorPSLpool_) || ~isempty(minorPSLpool_)
                majorPSLpool_ = PrincipalStressLineStruct();
                minorPSLpool_ = PrincipalStressLineStruct();
                majorCoordList_ = [];
                minorCoordList_ = [];                               
            end
            majorPSLindexList_ = struct('arr', []); majorPSLindexList_ = repmat(majorPSLindexList_, 1, numLevels_);        
            minorPSLindexList_ = struct('arr', []); minorPSLindexList_ = repmat(minorPSLindexList_, 1, numLevels_); 
            
            %%1. creating multi-resolution PSL distribution
            tic;
            index = 1;
            while index<=numLevels_
                seedPoints_ = seedPointsHistory_;
                seedPointsValence_ = zeros(size(seedPoints_,1), 2);
                if strcmp(functionality_, 'Major'), seedPointsValence_(:,2) = 1;
                elseif strcmp(functionality_, 'Minor'), seedPointsValence_(:,1) = 1; end
                mergeTrigger_ = minimumEpsilon_ * 2^(numLevels_-index) * tracingStepWidth_;
                PreprocessSeedgPoints();
                GenerateSpaceFillingPSLs();
                majorPSLindexList_(index) = FlexibleArrList();
                minorPSLindexList_(index) = FlexibleArrList();
                majorPSLindexList_(index).arr = 1:length(majorPSLpool_);
                minorPSLindexList_(index).arr = 1:length(minorPSLpool_);                
            end
            disp(['Done! It costs: ' sprintf('%10.3g',toc) 's']);
            
            %%2. building hierarchy
            BuildPSLs4Hierarchy(app.IMMajorDropDown.Items);
            
            %%3. Construct Patch-based PSL Geometries (Tube, Ribbon)
            ConstructPatchBasedPSLgeometry();
            
            %%4. coarsest level showing
            set(handleSeedPoints_, 'visible', 'off'); handleSeedPoints_ = [];
%             HierarchyDropDownValueChanged(app);            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {670, 670};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {290, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.WelcometotheStressFieldPanel = uipanel(app.HomeTab);
            app.WelcometotheStressFieldPanel.Title = 'Welcome to the Stress Field!';
            app.MinEpsilonEditFieldLabel = uilabel(app.WelcometotheStressFieldPanel);
            app.MinEpsilonEditFieldLabel.Text = 'Min. Epsilon';
            app.MinEpsilonEditField = uieditfield(app.WelcometotheStressFieldPanel, 'numeric');
            app.MinEpsilonEditField.ValueChangedFcn = createCallbackFcn(app, @MinEpsilonEditFieldValueChanged, true);
            app.MinEpsilonEditField.Value = 10;
            app.LevelsEditFieldLabel = uilabel(app.WelcometotheStressFieldPanel);
            app.LevelsEditFieldLabel.Text = 'Levels';
            app.LevelsEditField = uieditfield(app.WelcometotheStressFieldPanel, 'numeric');
            app.LevelsEditField.Value = 1;
            app.SeedingStrategyDropDownLabel = uilabel(app.WelcometotheStressFieldPanel);
            app.SeedingStrategyDropDownLabel.Text = 'Seeding Strategy';
            app.SeedingStrategyDropDown = uidropdown(app.WelcometotheStressFieldPanel);
            app.SeedingStrategyDropDown.Items = {'Volume', 'Surface', 'Near Topology (only Cartesian Mesh)'};
            app.SeedingStrategyDropDown.Value = 'Volume';
            app.GenerateSeedPointsButton = uibutton(app.WelcometotheStressFieldPanel, 'push');
            app.GenerateSeedPointsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSeedPointsButtonPushed, true);
            app.GenerateSeedPointsButton.Text = 'Generate Seed Points';
            app.FunctionalityDropDownLabel = uilabel(app.WelcometotheStressFieldPanel);
            app.FunctionalityDropDownLabel.Text = 'Functionality';
            app.FunctionalityDropDown = uidropdown(app.WelcometotheStressFieldPanel);
            app.FunctionalityDropDown.Items = {'Major&Minor', 'Major', 'Minor'};
            app.FunctionalityDropDown.Value = 'Major&Minor';
            app.SubmitButton = uibutton(app.WelcometotheStressFieldPanel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.ExpertSettingsPanel = uipanel(app.HomeTab);
            app.ExpertSettingsPanel.Title = 'Expert Settings';
            app.SnappingOptDropDownLabel = uilabel(app.ExpertSettingsPanel);
            app.SnappingOptDropDownLabel.Text = 'Snapping Opt';
            app.SnappingOptDropDown = uidropdown(app.ExpertSettingsPanel);
            app.SnappingOptDropDown.Items = {'OFF', 'ON'};
            app.SnappingOptDropDown.ValueChangedFcn = createCallbackFcn(app, @SnappingOptDropDownValueChanged, true);
            app.SnappingOptDropDown.Value = 'OFF';
            app.PSLSmoothingCtrlLabel = uilabel(app.ExpertSettingsPanel);
            app.PSLSmoothingCtrlLabel.Text = 'PSL Smoothing Ctrl';
            app.PSLSmoothingCtrlEditField = uieditfield(app.ExpertSettingsPanel, 'numeric');
            app.PSLSmoothingCtrlEditField.ValueChangedFcn = createCallbackFcn(app, @PSLSmoothingCtrlEditFieldValueChanged, true);
            app.PSLSmoothingCtrlEditField.Value = 20;
            app.MinPSLLengthCtrlEditFieldLabel = uilabel(app.ExpertSettingsPanel);
            app.MinPSLLengthCtrlEditFieldLabel.Text = 'Min. PSL Length Ctrl';
            app.MinPSLLengthCtrlEditField = uieditfield(app.ExpertSettingsPanel, 'numeric');
            app.MinPSLLengthCtrlEditField.ValueChangedFcn = createCallbackFcn(app, @MinPSLLengthCtrlEditFieldValueChanged, true);
            app.MinPSLLengthCtrlEditField.Value = 20;
            app.GPUAccDropDownLabel = uilabel(app.ExpertSettingsPanel);
            app.GPUAccDropDownLabel.Text = 'GPU Acc';
            app.GPUAccDropDown = uidropdown(app.ExpertSettingsPanel);
            app.GPUAccDropDown.Items = {'OFF', 'ON'};
            app.GPUAccDropDown.ValueChangedFcn = createCallbackFcn(app, @GPUAccDropDownValueChanged, true);
            app.GPUAccDropDown.Value = 'OFF';
            app.VolumeSeedPointsCtrlEditFieldLabel = uilabel(app.ExpertSettingsPanel);
            app.VolumeSeedPointsCtrlEditFieldLabel.Text = 'Volume Seed Points Ctrl';
            app.VolumeSeedPointsCtrlEditField = uieditfield(app.ExpertSettingsPanel, 'numeric');
            app.VolumeSeedPointsCtrlEditField.ValueChangedFcn = createCallbackFcn(app, @VolumeSeedPointsCtrlEditFieldValueChanged, true);
            app.VolumeSeedPointsCtrlEditField.Value = 5;
            app.StepSizeEditFieldLabel = uilabel(app.ExpertSettingsPanel);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.ExpertSettingsPanel, 'numeric');
            app.StepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @StepSizeEditFieldValueChanged, true);
            app.StepSizeEditField.Value = 1;
            app.InvestigationTab = uitab(app.TabGroup);
            app.InvestigationTab.Title = 'Investigation';
            app.PSLsResolutionCtrlPanel = uipanel(app.InvestigationTab);
            app.PSLsResolutionCtrlPanel.Title = 'PSLs Resolution Ctrl';
            app.IMMajorLabel = uilabel(app.PSLsResolutionCtrlPanel);
            app.IMMajorLabel.Text = 'IM (Major):';
            app.IMMajorDropDown = uidropdown(app.PSLsResolutionCtrlPanel);
            app.IMMajorDropDown.Items = {'Pure PSLs Density Ctrl', 'Principal Stress (PS)', 'von Mises Stress (vM)', 'PSLs Length', 'Open to 3rd Party'};
            app.IMMajorDropDown.Value = 'Pure PSLs Density Ctrl';
            app.MajorSliderLabel = uilabel(app.PSLsResolutionCtrlPanel);
            app.MajorSliderLabel.Text = 'Major';
            app.MajorSlider = uislider(app.PSLsResolutionCtrlPanel);
            app.MajorSlider.Limits = [0 1];
            app.IMMinorDropDownLabel = uilabel(app.PSLsResolutionCtrlPanel);
            app.IMMinorDropDownLabel.Text = 'IM (Minor):';
            app.IMMinorDropDown = uidropdown(app.PSLsResolutionCtrlPanel);
            app.IMMinorDropDown.Items = {'Pure PSLs Density Ctrl', 'Principal Stress (PS)', 'von Mises Stress (vM)', 'PSLs Length', 'Open to 3rd Party'};
            app.IMMinorDropDown.Value = 'Pure PSLs Density Ctrl';
            app.MinorSliderLabel = uilabel(app.PSLsResolutionCtrlPanel);
            app.MinorSliderLabel.Text = 'Minor';
            app.MinorSlider = uislider(app.PSLsResolutionCtrlPanel);
            app.MinorSlider.Limits = [0 1];
            app.PreferencePanel = uipanel(app.InvestigationTab);
            app.PreferencePanel.Title = 'Preference';
            app.PSLGeometryDropDownLabel = uilabel(app.PreferencePanel);
            app.PSLGeometryDropDownLabel.Text = 'PSL Geometry';
            app.PSLGeometryDropDown = uidropdown(app.PreferencePanel);
            app.PSLGeometryDropDown.Items = {'Tube (T)', 'Ribben (R)', 'MajorT&MinorR', 'MajorR&MinorT'};
            app.PSLGeometryDropDown.Value = 'Tube (T)';
            app.ColorSchemeDropDownLabel = uilabel(app.PreferencePanel);
            app.ColorSchemeDropDownLabel.Text = 'Color Scheme';
            app.ColorSchemeDropDown = uidropdown(app.PreferencePanel);
            app.ColorSchemeDropDown.Items = {'Red&Blue', 'Red&Blue_2', 'Red&Green', 'Spring&Summer', 'Autumn&Winter', 'Copper&Bone'};
            app.ColorSchemeDropDown.Value = 'Red&Blue';
            app.LightsDropDownLabel = uilabel(app.PreferencePanel);
            app.LightsDropDownLabel.Text = 'Lights';
            app.LightsDropDown = uidropdown(app.PreferencePanel);
            app.LightsDropDown.Items = {'None', 'Prefer', 'Head', 'Right', 'Left'};
            app.LightsDropDown.Value = 'None';
            app.ReflectionPropertyDropDownLabel = uilabel(app.PreferencePanel);
            app.ReflectionPropertyDropDownLabel.Text = 'Reflection Property';
            app.ReflectionPropertyDropDown = uidropdown(app.PreferencePanel);
            app.ReflectionPropertyDropDown.Items = {'None', 'Shiny', 'Dull', 'Metal'};
            app.ReflectionPropertyDropDown.Value = 'None';
            app.ScalarFieldEncodingDropDownLabel = uilabel(app.PreferencePanel);
            app.ScalarFieldEncodingDropDownLabel.Text = 'Scalar Field Encoding';
            app.ScalarFieldEncodingDropDown = uidropdown(app.PreferencePanel);
            app.ScalarFieldEncodingDropDown.Items = {'None', 'PS', 'vM', 'Normal Stress (X)', 'Normal Stress (Y)', 'Normal Stress (Z)', 'Shear Stress (YZ)', 'Shear Stress (ZX)', 'Shear Stress (XY)'};
            app.ScalarFieldEncodingDropDown.Value = 'None';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FilesMenu = uimenu(app.UIFigure);
            app.FilesMenu.Text = 'Files';
            app.ImportDatavtkMenu = uimenu(app.FilesMenu);
            app.ImportDatavtkMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportDatavtkMenuSelected, true);
            app.ImportDatavtkMenu.Text = 'Import Data (''.vtk'')';
            app.ResetMenu = uimenu(app.FilesMenu);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetMenuSelected, true);
            app.ResetMenu.Text = 'Reset';
            app.ClearAllMenu = uimenu(app.FilesMenu);
            app.ClearAllMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearAllMenuSelected, true);
            app.ClearAllMenu.Text = 'Clear All';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end